classdef rtbdemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        uipanel                   matlab.ui.container.Panel
        RotationsButton           matlab.ui.control.Button
        TransformationsButton     matlab.ui.control.Button
        TrajectoryButton          matlab.ui.control.Button
        CreateamodelButton        matlab.ui.control.Button
        AnimationButton           matlab.ui.control.Button
        JacobiansButton           matlab.ui.control.Button
        RenderedanimationButton   matlab.ui.control.Button
        ForwardkinematicsButton   matlab.ui.control.Button
        InversekinematicsButton   matlab.ui.control.Button
        InversedynamicsButton     matlab.ui.control.Button
        ForwarddynamicsButton     matlab.ui.control.Button
        BugnavigationButton       matlab.ui.control.Button
        pointturnButton           matlab.ui.control.Button
        DnavigationButton         matlab.ui.control.Button
        PRMnavigationButton       matlab.ui.control.Button
        EKFSLAMButton             matlab.ui.control.Button
        ParticlefilterlocalizationButton  matlab.ui.control.Button
        PosegraphSLAMButton       matlab.ui.control.Button
        DrivingtoaposeButton      matlab.ui.control.Button
        BraitenbergvehicleButton  matlab.ui.control.Button
        QuadrotorflyingButton     matlab.ui.control.Button
        ConsolepauseCheckBox      matlab.ui.control.CheckBox
        GeneralLabel              matlab.ui.control.Label
        ArmrobotLabel             matlab.ui.control.Label
        MobilerobotLabel          matlab.ui.control.Label
        GenericButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        demolist % Description
    end
    
    methods (Access = private)
        

        function results = demoButtonPushed(app, ui, event)
            99
            % create the options to pass through to runscript
            opts = {'begin', 'path', demopath};
            
            fprintf('mybutton\n');
            selection = event.Source.Text;
            % now look for it in the list of demos
            for i=1:size(app.demolist, 1)
                if selection == app.demolist(i,1)
                    % then run the appropriate script
                    script = app.demolist(i,2);
                    set(h, 'Visible', 'off');
                    
                    if cb.Value > 0
                        % pause after each command
                        opts1 = opts;
                    else
                        % no pause
                        
                        delay = 0;
                        % if delay given on command use that
                        if nargin > 0
                            delay = timeout;
                        end
                        opts1 = [opts, 'delay', delay];
                    end
                    
                    try
                        fprintf('attempt to run %s', opts1{:})
                        %runscript(script, opts1{:})
                    catch me
                        disp('error in executing demo script');
                        me.getReport()
                    end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            88
            app.demolist = [
            "Rotations", "rotation";
            "Transformations", "trans";
            "Joystick demo", "joytest";
            "Trajectory", "traj";
            "V-REP simulator", "vrepdemo";
            "Create a model", "robot";
            "Animation", "graphics";
            "Rendered animation", "puma_path";
            "3-point turn", "car_anim_rs";
            "Forward kinematics", "fkine";
            "Inverse kinematics", "ikine";
            "Jacobians", "jacob";
            "Inverse dynamics", "idyn";
            "Forward dynamics", "fdyn";
            "Symbolic", "symbolic";
            "Driving to a pose", "drivepose";
            "Quadrotor flying", "quadrotor";
            "Braitenberg vehicle", "braitnav";
            "Bug navigation", "bugnav";
            "D* navigation", "dstarnav";
            "PRM navigation", "prmnav";
            "SLAM demo", "slam";
            "Particle filter localization", "particlefilt";
            "Pose graph SLAM", "pgslam";
            ];
        
            children = app.uipanel.Children;
            for child = children'
                if string(child.Type) == "uibutton"
                    child.ButtonPushedFcn = @app.demoButtonPushed;
                end
            end
        end

        % Button pushed function: RotationsButton
        function RotationsButtonPushed(app, event)
            
        end

        % Button pushed function: CreateamodelButton, GenericButton
        function GenericButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.uipanel = uipanel(app.figure1);
            app.RotationsButton = uibutton(app.uipanel, 'push');
            app.RotationsButton.ButtonPushedFcn = createCallbackFcn(app, @RotationsButtonPushed, true);
            app.RotationsButton.Text = 'Rotations';
            app.TransformationsButton = uibutton(app.uipanel, 'push');
            app.TransformationsButton.Text = 'Transformations';
            app.TrajectoryButton = uibutton(app.uipanel, 'push');
            app.TrajectoryButton.Text = 'Trajectory';
            app.CreateamodelButton = uibutton(app.uipanel, 'push');
            app.CreateamodelButton.ButtonPushedFcn = createCallbackFcn(app, @GenericButtonPushed, true);
            app.CreateamodelButton.Text = 'Create a model';
            app.AnimationButton = uibutton(app.uipanel, 'push');
            app.AnimationButton.Text = 'Animation';
            app.JacobiansButton = uibutton(app.uipanel, 'push');
            app.JacobiansButton.Text = 'Jacobians';
            app.RenderedanimationButton = uibutton(app.uipanel, 'push');
            app.RenderedanimationButton.Text = 'Rendered animation';
            app.ForwardkinematicsButton = uibutton(app.uipanel, 'push');
            app.ForwardkinematicsButton.Text = 'Forward kinematics';
            app.InversekinematicsButton = uibutton(app.uipanel, 'push');
            app.InversekinematicsButton.Text = 'Inverse kinematics';
            app.InversedynamicsButton = uibutton(app.uipanel, 'push');
            app.InversedynamicsButton.Text = 'Inverse dynamics';
            app.ForwarddynamicsButton = uibutton(app.uipanel, 'push');
            app.ForwarddynamicsButton.Text = 'Forward dynamics';
            app.BugnavigationButton = uibutton(app.uipanel, 'push');
            app.BugnavigationButton.Text = 'Bug navigation';
            app.pointturnButton = uibutton(app.uipanel, 'push');
            app.pointturnButton.Text = '3-point turn';
            app.DnavigationButton = uibutton(app.uipanel, 'push');
            app.DnavigationButton.Text = 'D* navigation';
            app.PRMnavigationButton = uibutton(app.uipanel, 'push');
            app.PRMnavigationButton.Text = 'PRM navigation';
            app.EKFSLAMButton = uibutton(app.uipanel, 'push');
            app.EKFSLAMButton.Text = 'EKF SLAM';
            app.ParticlefilterlocalizationButton = uibutton(app.uipanel, 'push');
            app.ParticlefilterlocalizationButton.Text = 'Particle filter localization';
            app.PosegraphSLAMButton = uibutton(app.uipanel, 'push');
            app.PosegraphSLAMButton.Text = 'Pose graph SLAM';
            app.DrivingtoaposeButton = uibutton(app.uipanel, 'push');
            app.DrivingtoaposeButton.Text = 'Driving to a pose';
            app.BraitenbergvehicleButton = uibutton(app.uipanel, 'push');
            app.BraitenbergvehicleButton.Text = 'Braitenberg vehicle';
            app.QuadrotorflyingButton = uibutton(app.uipanel, 'push');
            app.QuadrotorflyingButton.Text = 'Quadrotor flying';
            app.ConsolepauseCheckBox = uicheckbox(app.uipanel);
            app.ConsolepauseCheckBox.Text = 'Console pause';
            app.ConsolepauseCheckBox.Value = true;
            app.GeneralLabel = uilabel(app.uipanel);
            app.GeneralLabel.Text = 'General';
            app.ArmrobotLabel = uilabel(app.uipanel);
            app.ArmrobotLabel.Text = 'Arm robot';
            app.MobilerobotLabel = uilabel(app.uipanel);
            app.MobilerobotLabel.Text = 'Mobile robot';
            app.GenericButton = uibutton(app.uipanel, 'push');
            app.GenericButton.ButtonPushedFcn = createCallbackFcn(app, @GenericButtonPushed, true);
            app.GenericButton.Enable = 'off';
            app.GenericButton.Visible = 'off';
            app.GenericButton.Text = 'Generic';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rtbdemo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end