classdef rtbdemo_gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        uipanel                   matlab.ui.container.Panel
        RotationsButton           matlab.ui.control.Button
        TransformationsButton     matlab.ui.control.Button
        TrajectoryButton          matlab.ui.control.Button
        CreateamodelButton        matlab.ui.control.Button
        AnimationButton           matlab.ui.control.Button
        JacobiansButton           matlab.ui.control.Button
        RenderedanimationButton   matlab.ui.control.Button
        ForwardkinematicsButton   matlab.ui.control.Button
        InversekinematicsButton   matlab.ui.control.Button
        InversedynamicsButton     matlab.ui.control.Button
        ForwarddynamicsButton     matlab.ui.control.Button
        BugnavigationButton       matlab.ui.control.Button
        pointturnButton           matlab.ui.control.Button
        DnavigationButton         matlab.ui.control.Button
        PRMnavigationButton       matlab.ui.control.Button
        EKFSLAMButton             matlab.ui.control.Button
        ParticlefilterlocalizationButton  matlab.ui.control.Button
        PosegraphSLAMButton       matlab.ui.control.Button
        DrivingtoaposeButton      matlab.ui.control.Button
        BraitenbergvehicleButton  matlab.ui.control.Button
        QuadrotorflyingButton     matlab.ui.control.Button
        ConsolepauseCheckBox      matlab.ui.control.CheckBox
        GeneralLabel              matlab.ui.control.Label
        ArmrobotLabel             matlab.ui.control.Label
        MobilerobotLabel          matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fprintf('hello from setup_buttons');
                demos = {
        'Rotations', 'rotation';
        'Transformations', 'trans';
        'Joystick demo', 'joytest';
        'Trajectory', 'traj';
        'V-REP simulator', 'vrepdemo';
        'Create a model', 'robot';
        'Animation', 'graphics';
        'Rendered animation', 'puma_path';
        '3-point turn', 'car_anim_rs';
        'Forward kinematics', 'fkine';
        'Inverse kinematics', 'ikine';
        'Jacobians', 'jacob';
        'Inverse dynamics', 'idyn';
        'Forward dynamics', 'fdyn';
        'Symbolic', 'symbolic';
        'Driving to a pose', 'drivepose';
        'Quadrotor flying', 'quadrotor';
        'Braitenberg vehicle', 'braitnav';
        'Bug navigation', 'bugnav';
        'D* navigation', 'dstarnav';
        'PRM navigation', 'prmnav';
        'SLAM demo', 'slam';
        'Particle filter localization', 'particlefilt';
        'Pose graph SLAM', 'pgslam';
        };
        for child = app.uipanel.Children'
            if child
        end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.uipanel = uipanel(app.figure1);
            app.RotationsButton = uibutton(app.uipanel, 'push');
            app.RotationsButton.Text = 'Rotations';
            app.TransformationsButton = uibutton(app.uipanel, 'push');
            app.TransformationsButton.Text = 'Transformations';
            app.TrajectoryButton = uibutton(app.uipanel, 'push');
            app.TrajectoryButton.Text = 'Trajectory';
            app.CreateamodelButton = uibutton(app.uipanel, 'push');
            app.CreateamodelButton.Text = 'Create a model';
            app.AnimationButton = uibutton(app.uipanel, 'push');
            app.AnimationButton.Text = 'Animation';
            app.JacobiansButton = uibutton(app.uipanel, 'push');
            app.JacobiansButton.Text = 'Jacobians';
            app.RenderedanimationButton = uibutton(app.uipanel, 'push');
            app.RenderedanimationButton.Text = 'Rendered animation';
            app.ForwardkinematicsButton = uibutton(app.uipanel, 'push');
            app.ForwardkinematicsButton.Text = 'Forward kinematics';
            app.InversekinematicsButton = uibutton(app.uipanel, 'push');
            app.InversekinematicsButton.Text = 'Inverse kinematics';
            app.InversedynamicsButton = uibutton(app.uipanel, 'push');
            app.InversedynamicsButton.Text = 'Inverse dynamics';
            app.ForwarddynamicsButton = uibutton(app.uipanel, 'push');
            app.ForwarddynamicsButton.Text = 'Forward dynamics';
            app.BugnavigationButton = uibutton(app.uipanel, 'push');
            app.BugnavigationButton.Text = 'Bug navigation';
            app.pointturnButton = uibutton(app.uipanel, 'push');
            app.pointturnButton.Text = '3-point turn';
            app.DnavigationButton = uibutton(app.uipanel, 'push');
            app.DnavigationButton.Text = 'D* navigation';
            app.PRMnavigationButton = uibutton(app.uipanel, 'push');
            app.PRMnavigationButton.Text = 'PRM navigation';
            app.EKFSLAMButton = uibutton(app.uipanel, 'push');
            app.EKFSLAMButton.Text = 'EKF SLAM';
            app.ParticlefilterlocalizationButton = uibutton(app.uipanel, 'push');
            app.ParticlefilterlocalizationButton.Text = 'Particle filter localization';
            app.PosegraphSLAMButton = uibutton(app.uipanel, 'push');
            app.PosegraphSLAMButton.Text = 'Pose graph SLAM';
            app.DrivingtoaposeButton = uibutton(app.uipanel, 'push');
            app.DrivingtoaposeButton.Text = 'Driving to a pose';
            app.BraitenbergvehicleButton = uibutton(app.uipanel, 'push');
            app.BraitenbergvehicleButton.Text = 'Braitenberg vehicle';
            app.QuadrotorflyingButton = uibutton(app.uipanel, 'push');
            app.QuadrotorflyingButton.Text = 'Quadrotor flying';
            app.ConsolepauseCheckBox = uicheckbox(app.uipanel);
            app.ConsolepauseCheckBox.Text = 'Console pause';
            app.ConsolepauseCheckBox.Value = true;
            app.GeneralLabel = uilabel(app.uipanel);
            app.GeneralLabel.Text = 'General';
            app.ArmrobotLabel = uilabel(app.uipanel);
            app.ArmrobotLabel.Text = 'Arm robot';
            app.MobilerobotLabel = uilabel(app.uipanel);
            app.MobilerobotLabel.Text = 'Mobile robot';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rtbdemo_gui_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end